classdef NewStructure1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewStructureUIFigure         matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        NewStructureLeftPanel        matlab.ui.container.Panel
        DirectoryTypeEditFieldLabel  matlab.ui.control.Label
        DirectoryTypeEditField       matlab.ui.control.EditField
        DirectoryStructureTextAreaLabel  matlab.ui.control.Label
        DirectoryStructureTextArea   matlab.ui.control.TextArea
        TestLabel                    matlab.ui.control.Label
        ConfirmButton                matlab.ui.control.Button
        NewStructureRightPanel       matlab.ui.container.Panel
        DirectoryOutline             matlab.ui.container.Tree
        DirectoryOutlineLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        DirectoryTypeName = ' Test';
        DirectoryStructure = readtable( fullfile('directoryStructures','CreateNewStructure.csv'), 'ReadVariableNames', false);
        DirectoryTypeList
    end
    
    methods (Access = private)
        
        function NewStructureDisplayFcn(app)
            for row = 1:height( app.DirectoryStructure)
                structure{row} = strjoin( app.DirectoryStructure{row,:}( ~cellfun( 'isempty',app.DirectoryStructure{row,:})), ',');
            end
            
            app.DirectoryStructureTextArea.Value = structure;
            
            Node.DirectoryType = uitreenode( app.DirectoryOutline, 'Text', app.DirectoryTypeName);
            
            for row = 1:height( app.DirectoryStructure)
                pathFolders = app.DirectoryStructure{row,:}( ~cellfun( 'isempty',app.DirectoryStructure{row,:}));
                
                if isequal( pathFolders{1}, pathFolders{end})
                    Node.( pathFolders{end}) = uitreenode( Node.DirectoryType, 'Text', pathFolders{end});
                else
                    try
                        Node.( [pathFolders{1:end}]) = uitreenode( Node.( [pathFolders{1:end - 1}]), 'Text', pathFolders{end});
                    catch
                        app.DirectoryStructureTextArea.Value{end + 1} = strjoin( pathFolders(1:end - 1), ',');
                        DirectoryStructureTextAreaValueChanged(app, true);
                        break
                    end
                    
                end
                
            end
            
            expand( app.DirectoryOutline, 'all')
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.DirectoryTypeList = table2cell(readtable( fullfile('directoryStructures','DirectoryTypeList.csv'), 'ReadVariableNames', false, 'Delimiter', '\n'));
            
            NewStructureDisplayFcn(app);
        end

        % Value changed function: DirectoryStructureTextArea
        function DirectoryStructureTextAreaValueChanged(app, event)
            for row = 1:size( app.DirectoryStructureTextArea.Value)
                structure{row,1} = split( app.DirectoryStructureTextArea.Value{row}, ',')';
            end
            
            len = max( cellfun(@length, structure));
            
            for row = 1:size( app.DirectoryStructureTextArea.Value)
                structure{row,1}(end + 1:len) = {''};
            end
            
            structure = vertcat( structure{:});
            structure = sortrows( structure);
            
            app.DirectoryStructure = cell2table( structure);
            
            delete( app.DirectoryOutline.Children);
            NewStructureDisplayFcn( app);
            
        end

        % Value changed function: DirectoryTypeEditField
        function DirectoryTypeEditFieldValueChanged(app, event)
            app.DirectoryTypeName = [app.DirectoryTypeEditField.Value ' Test'];
            delete( app.DirectoryOutline.Children);
            NewStructureDisplayFcn( app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if exist( fullfile( 'directoryStructures', [app.DirectoryTypeName( ~isspace( app.DirectoryTypeName)) '.csv']), 'file')
                msg = ['Directory structure for ' app.DirectoryTypeName ' already exists.'];
                options = {'Overwrite', 'Save As New', 'Cancel'};
                selection = uiconfirm( app.NewStructureUIFigure, msg, 'Confirm', 'Options', options, 'DefaultOption', 2, 'Icon', 'warning');
                
                if isequal( selection, options{2})
                    n = 1;
                    while exist( fullfile( 'directoryStructures', [app.DirectoryTypeName( ~isspace( app.DirectoryTypeName)) n '.csv']), 'file')
                        n = n + 1;
                    end
                    
                    app.DirectoryTypeName = [app.DirectoryTypeName ' ' sprintf('%i',n)];
                    
                elseif isequal( selection, options{3})
                    return;
                    
                end
                
            end
            
            writetable( app.DirectoryStructure, fullfile( 'directoryStructures', [app.DirectoryTypeName( ~isspace( app.DirectoryTypeName)) '.csv']), 'WriteVariableNames', false);
            
            app.DirectoryTypeList{end} = app.DirectoryTypeName;
            [app.DirectoryTypeList, index] = sortrows( app.DirectoryTypeList);
            app.DirectoryTypeList{end + 1} = 'New Structure';
            writetable( cell2table( app.DirectoryTypeList), fullfile( 'directoryStructures', 'DirectoryTypeList.csv'), 'WriteVariableNames', false);
            
            app.MainApp.DirectoryTypeDropDown.Items = table2cell(readtable( fullfile('directoryStructures','DirectoryTypeList.csv'), 'ReadVariable', false, 'Delimiter', '\n'))';
            app.MainApp.DirectoryTypeDropDown.Value = app.MainApp.DirectoryTypeDropDown.Items{ find( index == length(app.DirectoryTypeList) - 1)};
            app.MainApp.DirectoryTypeDropDown.Enable = 'on';
            generateDisplayFcn( app.MainApp);
            delete(app);
            
        end

        % Close request function: NewStructureUIFigure
        function NewStructureUIFigureCloseRequest(app, event)
            app.MainApp.DirectoryTypeDropDown.Enable = 'on';
            app.MainApp.DirectoryTypeDropDown.Value = app.MainApp.DirectoryTypeDropDown.Items{1};
            generateDisplayFcn(app.MainApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewStructureUIFigure = uifigure('Visible', 'off');
            app.NewStructureUIFigure.Name = 'New Structure';
            app.NewStructureUIFigure.CloseRequestFcn = createCallbackFcn(app, @NewStructureUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.NewStructureUIFigure);
            app.NewStructureLeftPanel = uipanel(app.GridLayout);
            app.DirectoryTypeEditFieldLabel = uilabel(app.NewStructureLeftPanel);
            app.DirectoryTypeEditFieldLabel.Text = 'Directory Type:';
            app.DirectoryTypeEditField = uieditfield(app.NewStructureLeftPanel, 'text');
            app.DirectoryTypeEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryTypeEditFieldValueChanged, true);
            app.DirectoryStructureTextAreaLabel = uilabel(app.NewStructureLeftPanel);
            app.DirectoryStructureTextAreaLabel.Text = 'Directory Structure:';
            app.DirectoryStructureTextArea = uitextarea(app.NewStructureLeftPanel);
            app.DirectoryStructureTextArea.ValueChangedFcn = createCallbackFcn(app, @DirectoryStructureTextAreaValueChanged, true);
            app.TestLabel = uilabel(app.NewStructureLeftPanel);
            app.TestLabel.Enable = 'off';
            app.TestLabel.Text = 'Test';
            app.ConfirmButton = uibutton(app.NewStructureLeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.NewStructureRightPanel = uipanel(app.GridLayout);
            app.DirectoryOutline = uitree(app.NewStructureRightPanel);
            app.DirectoryOutlineLabel = uilabel(app.NewStructureRightPanel);
            app.DirectoryOutlineLabel.Text = 'Directory Outline:';
            % Show the figure after all components are created
            app.NewStructureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewStructure1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewStructureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewStructureUIFigure)
        end
    end
end