classdef Update1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UpdateUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        UpdateLeftPanel             matlab.ui.container.Panel
        DirectoryStructureTextAreaLabel  matlab.ui.control.Label
        DirectoryStructureTextArea  matlab.ui.control.TextArea
        ConfirmButton               matlab.ui.control.Button
        UpdateRightPanel            matlab.ui.container.Panel
        DirectoryOutline            matlab.ui.container.Tree
        DirectoryOutlineLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        DirectoryName
        DirectoryStructure
    end
    
    methods (Access = private)
        
        function UpdateDisplayFcn(app)
            for row = 1:height( app.DirectoryStructure)
                structure{row} = strjoin( app.DirectoryStructure{row,:}( ~cellfun( 'isempty',app.DirectoryStructure{row,:})), ',');
            end
            
            app.DirectoryStructureTextArea.Value = structure;
            
            Node.DirectoryType = uitreenode( app.DirectoryOutline, 'Text', app.DirectoryName);
            
            for row = 1:height( app.DirectoryStructure)
                pathFolders = app.DirectoryStructure{row,:}( ~cellfun( 'isempty',app.DirectoryStructure{row,:}));
                
                if isequal( pathFolders{1}, pathFolders{end})
                    Node.( pathFolders{end}) = uitreenode( Node.DirectoryType, 'Text', pathFolders{end});
                else
                    try
                        Node.( [pathFolders{1:end}]) = uitreenode( Node.( [pathFolders{1:end - 1}]), 'Text', pathFolders{end});
                    catch
                        app.DirectoryStructureTextArea.Value{end + 1} = strjoin( pathFolders(1:end - 1), ',');
                        DirectoryStructureTextAreaValueChanged(app, true);
                        break
                    end
                    
                end
                
            end
            
            expand( app.DirectoryOutline, 'all')
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, directoryName)
            app.MainApp = mainapp;
            app.DirectoryName = directoryName;
            app.DirectoryStructure = readtable( fullfile('existingDirectory', [app.DirectoryName '.csv']), 'ReadVariableNames', false);
            UpdateDisplayFcn(app);
        end

        % Value changed function: DirectoryStructureTextArea
        function DirectoryStructureTextAreaValueChanged(app, event)
            structureText = app.DirectoryStructureTextArea.Value( ~cellfun( 'isempty', app.DirectoryStructureTextArea.Value));
            for row = 1:length( structureText)
                structure{row,1} = split( structureText{row}, ',')';
            end
            
            len = max( cellfun(@length, structure));
            
            for row = 1:size( app.DirectoryStructureTextArea.Value)
                structure{row,1}(end + 1:len) = {''};
            end
            
            structure = vertcat( structure{:});
            structure = sortrows( structure);
            
            app.DirectoryStructure = cell2table( structure);
            
            delete( app.DirectoryOutline.Children);
            UpdateDisplayFcn( app);
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            msg = ['You are about to update ' app.DirectoryName '.' newline 'This action is irreversible.' newline 'Do you still wish to continue?'];
            options = {'Continue', 'Cancel'};
            selection = uiconfirm( app.UpdateUIFigure, msg, 'Confirm', 'Options', options, 'DefaultOption', 1, 'Icon', 'warning');
            
            if isequal( selection, options{2})
                return;
            end
            
            writetable( app.DirectoryStructure, fullfile( 'existingDirectory', [app.DirectoryName '.csv']), 'WriteVariableNames', false);
            
%             updateDirectory( app.DirectoryStructure);
            
            app.MainApp.DirectoryDropDown.Enable = 'on';
            delete(app.MainApp.UpdateDirectoryOutline.Children);
            updateDisplayFcn( app.MainApp);
            delete(app);
            
        end

        % Close request function: UpdateUIFigure
        function UpdateUIFigureCloseRequest(app, event)
            app.MainApp.DirectoryDropDown.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UpdateUIFigure = uifigure('Visible', 'off');
            app.UpdateUIFigure.Name = 'Update';
            app.UpdateUIFigure.CloseRequestFcn = createCallbackFcn(app, @UpdateUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UpdateUIFigure);
            app.UpdateLeftPanel = uipanel(app.GridLayout);
            app.DirectoryStructureTextAreaLabel = uilabel(app.UpdateLeftPanel);
            app.DirectoryStructureTextAreaLabel.Text = 'Directory Structure:';
            app.DirectoryStructureTextArea = uitextarea(app.UpdateLeftPanel);
            app.DirectoryStructureTextArea.ValueChangedFcn = createCallbackFcn(app, @DirectoryStructureTextAreaValueChanged, true);
            app.ConfirmButton = uibutton(app.UpdateLeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.UpdateRightPanel = uipanel(app.GridLayout);
            app.DirectoryOutline = uitree(app.UpdateRightPanel);
            app.DirectoryOutlineLabel = uilabel(app.UpdateRightPanel);
            app.DirectoryOutlineLabel.Text = 'Directory Outline:';
            % Show the figure after all components are created
            app.UpdateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Update1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UpdateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UpdateUIFigure)
        end
    end
end