classdef generateTest1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        generateTestUIFigure         matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CreateTab                    matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        CreateLeftPanel              matlab.ui.container.Panel
        DirectoryTypeDropDownLabel   matlab.ui.control.Label
        DirectoryTypeDropDown        matlab.ui.control.DropDown
        DirectoryNameEditFieldLabel  matlab.ui.control.Label
        DirectoryNameEditField       matlab.ui.control.EditField
        CreateButton                 matlab.ui.control.Button
        CreateRightPanel             matlab.ui.container.Panel
        CreateDirectoryOutline       matlab.ui.container.Tree
        DirectoryOutlineLabel        matlab.ui.control.Label
        UpdateTab                    matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UpdateRightPanel             matlab.ui.container.Panel
        DirectoryDropDownLabel       matlab.ui.control.Label
        DirectoryDropDown            matlab.ui.control.DropDown
        UpdateButton                 matlab.ui.control.Button
        UpdateLeftPanel              matlab.ui.container.Panel
        UpdateDirectoryOutline       matlab.ui.container.Tree
        DirectoryOutlineLabel_2      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CreateNewApp
        DirectoryType = 'MultiAtlasTest';
        CreateDirectoryOutlineDirectory = '';
        DirectoryStructure
    end
    
    methods (Access = private)
        
        function DisplayFcn(app)
            app.DirectoryStructure = readtable( fullfile('directoryStructures', [app.DirectoryType '.csv']), 'ReadVariableNames', false);
            Node.Directory = uitreenode( app.CreateDirectoryOutline, 'Text', app.CreateDirectoryOutlineDirectory);
            
            for row = 1:height( app.DirectoryStructure)
                pathFolders = app.DirectoryStructure{row,:}( ~cellfun( 'isempty',app.DirectoryStructure{row,:}));
                
                if isequal( pathFolders{1}, pathFolders{end})
                    Node.( pathFolders{end}) = uitreenode( Node.Directory, 'Text', pathFolders{end});
                else
                    Node.( [pathFolders{1:end}]) = uitreenode( Node.( [pathFolders{1:end - 1}]), 'Text', pathFolders{end});
                end
                
            end
            
            expand( app.CreateDirectoryOutline, 'all')
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DisplayFcn(app);
        end

        % Value changed function: DirectoryTypeDropDown
        function DirectoryTypeDropDownValueChanged(app, event)
            if app.DirectoryTypeDropDown.Value == 'Create New'
                app.CreateNewApp = CreateNew1(app);
            else
                app.DirectoryType = app.DirectoryTypeDropDown.Value( ~isspace( app.DirectoryTypeDropDown.Value));
                delete(app.CreateDirectoryOutline.Children);
                DisplayFcn(app);
            end
        end

        % Value changed function: DirectoryNameEditField
        function DirectoryNameEditFieldValueChanged(app, event)
            app.CreateDirectoryOutlineDirectory = app.DirectoryNameEditField.Value;
            delete(app.CreateDirectoryOutline.Children);
            DisplayFcn(app);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            
        end

        % Value changed function: DirectoryDropDown
        function DirectoryDropDownValueChanged(app, event)
            value = app.DirectoryDropDown.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
        end

        % Close request function: generateTestUIFigure
        function generateTestUIFigureCloseRequest(app, event)
            delete(app.CreateNewApp);    
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.generateTestUIFigure = uifigure('Visible', 'off');
            app.generateTestUIFigure.Name = 'generateTest';
            app.generateTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @generateTestUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.generateTestUIFigure);
            app.CreateTab = uitab(app.TabGroup);
            app.CreateTab.Title = 'Create';
            app.GridLayout = uigridlayout(app.CreateTab);
            app.CreateLeftPanel = uipanel(app.GridLayout);
            app.DirectoryTypeDropDownLabel = uilabel(app.CreateLeftPanel);
            app.DirectoryTypeDropDownLabel.Text = 'Directory Type:';
            app.DirectoryTypeDropDown = uidropdown(app.CreateLeftPanel);
            app.DirectoryTypeDropDown.Items = {'Multi Atlas Test', 'Preprocessing Test', 'Respiration Test', 'Create New'};
            app.DirectoryTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectoryTypeDropDownValueChanged, true);
            app.DirectoryTypeDropDown.Value = 'Multi Atlas Test';
            app.DirectoryNameEditFieldLabel = uilabel(app.CreateLeftPanel);
            app.DirectoryNameEditFieldLabel.Text = 'Directory Name:';
            app.DirectoryNameEditField = uieditfield(app.CreateLeftPanel, 'text');
            app.DirectoryNameEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryNameEditFieldValueChanged, true);
            app.CreateButton = uibutton(app.CreateLeftPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CreateRightPanel = uipanel(app.GridLayout);
            app.CreateDirectoryOutline = uitree(app.CreateRightPanel);
            app.DirectoryOutlineLabel = uilabel(app.CreateRightPanel);
            app.DirectoryOutlineLabel.Text = 'Directory Outline:';
            app.UpdateTab = uitab(app.TabGroup);
            app.UpdateTab.Title = 'Update';
            app.GridLayout2 = uigridlayout(app.UpdateTab);
            app.UpdateRightPanel = uipanel(app.GridLayout2);
            app.DirectoryDropDownLabel = uilabel(app.UpdateRightPanel);
            app.DirectoryDropDownLabel.Text = 'Directory:';
            app.DirectoryDropDown = uidropdown(app.UpdateRightPanel);
            app.DirectoryDropDown.Items = {};
            app.DirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectoryDropDownValueChanged, true);
            app.DirectoryDropDown.Value = {};
            app.UpdateButton = uibutton(app.UpdateRightPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UpdateLeftPanel = uipanel(app.GridLayout2);
            app.UpdateDirectoryOutline = uitree(app.UpdateLeftPanel);
            app.DirectoryOutlineLabel_2 = uilabel(app.UpdateLeftPanel);
            app.DirectoryOutlineLabel_2.Text = 'Directory Outline:';
            % Show the figure after all components are created
            app.generateTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generateTest1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.generateTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.generateTestUIFigure)
        end
    end
end